classdef Continuation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        continuationtoolInstituteofDynamicsandVibrationResearchUIFigure  matlab.ui.Figure
        OpencontinuationfunctionMenu    matlab.ui.container.Menu
        OpensrcfolderMenu               matlab.ui.container.Menu
        WatchdocumentationMenu_2        matlab.ui.container.Menu
        WatchGitHubprojectMenu_2        matlab.ui.container.Menu
        ContactUsMenu                   matlab.ui.container.Menu
        EnabletoolbarinplotCheckBox     matlab.ui.control.CheckBox
        EnablemenubarinplotCheckBox     matlab.ui.control.CheckBox
        IDS2021TidoKubatschek18082021Label  matlab.ui.control.Label
        CommandWindowLabel              matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        FunctionintegrationLabel        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        pathcontinuationtoolLabel       matlab.ui.control.Label
        ImplementfunctionbyDropDown     matlab.ui.control.DropDown
        ImplementfunctionbyDropDownLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        ShowadvancedoptionsCheckBox     matlab.ui.control.CheckBox
        Function_nameEditField          matlab.ui.control.EditField
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Function_nameEditFieldLabel     matlab.ui.control.Label
        advancedoptionsLabel            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        numericjacobianTab_2            matlab.ui.container.Tab
        diffquotDropDown                matlab.ui.control.DropDown
        diffquotDropDownLabel           matlab.ui.control.Label
        reversecontrolTab               matlab.ui.container.Tab
        reverseDropDown                 matlab.ui.control.DropDown
        reverseDropDownLabel            matlab.ui.control.Label
        includereverseDropDown          matlab.ui.control.DropDown
        includereverseDropDownLabel     matlab.ui.control.Label
        alphareverseEditField           matlab.ui.control.NumericEditField
        alphareverseEditFieldLabel      matlab.ui.control.Label
        scalingTab                      matlab.ui.container.Tab
        dscale_minEditField             matlab.ui.control.EditField
        dscale_minEditFieldLabel        matlab.ui.control.Label
        dscale0EditField                matlab.ui.control.EditField
        dscale0EditFieldLabel           matlab.ui.control.Label
        scalingDropDown                 matlab.ui.control.DropDown
        scalingDropDownLabel            matlab.ui.control.Label
        breakTab                        matlab.ui.container.Tab
        breakfunctionEditField          matlab.ui.control.EditField
        breakfunctionEditFieldLabel     matlab.ui.control.Label
        initialdirectionTab_2           matlab.ui.container.Tab
        directionEditField              matlab.ui.control.EditField
        directionEditFieldLabel         matlab.ui.control.Label
        closedcurvedetectionTab_2       matlab.ui.container.Tab
        closedcurvedetectionDropDown    matlab.ui.control.DropDown
        closedcurvedetectionDropDownLabel  matlab.ui.control.Label
        closed_counterEditField         matlab.ui.control.NumericEditField
        closed_counterEditFieldLabel    matlab.ui.control.Label
        homotopyTab                     matlab.ui.container.Tab
        homotopyerrorcounterEditField   matlab.ui.control.NumericEditField
        homotopyerrorcounterEditFieldLabel  matlab.ui.control.Label
        homotopyDropDown                matlab.ui.control.DropDown
        homotopyDropDownLabel           matlab.ui.control.Label
        deflationTab                    matlab.ui.container.Tab
        deflationerrorcounterEditField  matlab.ui.control.NumericEditField
        deflationerrorcounterEditFieldLabel  matlab.ui.control.Label
        deflationDropDown               matlab.ui.control.DropDown
        deflationDropDownLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        lambdaandloopspecificationsTab  matlab.ui.container.Tab
        l_endEditField                  matlab.ui.control.EditField
        l_endEditFieldLabel             matlab.ui.control.Label
        l_startEditField                matlab.ui.control.EditField
        l_startEditFieldLabel           matlab.ui.control.Label
        v_0EditField                    matlab.ui.control.EditField
        v_0EditFieldLabel               matlab.ui.control.Label
        stepback_error_counterEditField  matlab.ui.control.NumericEditField
        stepback_error_counterEditFieldLabel  matlab.ui.control.Label
        n_step_maxEditField             matlab.ui.control.NumericEditField
        n_step_maxEditFieldLabel        matlab.ui.control.Label
        n_iter_optEditField             matlab.ui.control.NumericEditField
        n_iter_optEditFieldLabel        matlab.ui.control.Label
        maxerrorcounterEditField        matlab.ui.control.NumericEditField
        maxerrorcounterEditFieldLabel   matlab.ui.control.Label
        l_targetEditField               matlab.ui.control.EditField
        l_targetEditFieldLabel          matlab.ui.control.Label
        l_0EditField                    matlab.ui.control.EditField
        l_0EditFieldLabel               matlab.ui.control.Label
        CorrectorandstepsizemethodTab   matlab.ui.container.Tab
        startstepsizeEditField          matlab.ui.control.EditField
        startstepsizeEditFieldLabel     matlab.ui.control.Label
        minstepsizeEditField            matlab.ui.control.EditField
        minstepsizeEditFieldLabel       matlab.ui.control.Label
        maxstepsizeEditField_2          matlab.ui.control.EditField
        maxstepsizeEditField_2Label     matlab.ui.control.Label
        step_e_maxEditField             matlab.ui.control.NumericEditField
        step_e_maxEditFieldLabel        matlab.ui.control.Label
        step_size_angleEditField        matlab.ui.control.NumericEditField
        step_size_angleEditFieldLabel   matlab.ui.control.Label
        step_size_alpha_maxEditField    matlab.ui.control.NumericEditField
        step_size_alpha_maxEditFieldLabel  matlab.ui.control.Label
        stepsizecontrolmethodDropDown   matlab.ui.control.DropDown
        stepsizecontrolmethodDropDownLabel  matlab.ui.control.Label
        CorrectormethodDropDown         matlab.ui.control.DropDown
        CorrectormethodDropDownLabel    matlab.ui.control.Label
        predictorTab                    matlab.ui.container.Tab
        predictor_polynomial_orderEditField  matlab.ui.control.NumericEditField
        predictor_polynomial_orderEditFieldLabel  matlab.ui.control.Label
        predictor_polynomial_fitEditField  matlab.ui.control.NumericEditField
        predictor_polynomial_fitEditFieldLabel  matlab.ui.control.Label
        predictor_solverDropDown        matlab.ui.control.DropDown
        predictor_solverDropDownLabel   matlab.ui.control.Label
        predictor_polynomial_adaptiveDropDown  matlab.ui.control.DropDown
        predictor_polynomial_adaptiveDropDownLabel  matlab.ui.control.Label
        predictorDropDown               matlab.ui.control.DropDown
        predictorDropDownLabel          matlab.ui.control.Label
        plotdisplayTab                  matlab.ui.container.Tab
        ShowDisplayDropDown             matlab.ui.control.DropDown
        ShowDisplayDropDownLabel        matlab.ui.control.Label
        live_plot_figEditField          matlab.ui.control.EditField
        live_plot_figEditFieldLabel     matlab.ui.control.Label
        plot_vars_indexEditField_2      matlab.ui.control.EditField
        plot_vars_indexEditField_2Label  matlab.ui.control.Label
        plot_var_of_interestEditField   matlab.ui.control.EditField
        plot_var_of_interestEditFieldLabel  matlab.ui.control.Label
        plot_pauseEditField             matlab.ui.control.EditField
        plot_pauseEditFieldLabel        matlab.ui.control.Label
        plotDropDown                    matlab.ui.control.DropDown
        plotDropDownLabel               matlab.ui.control.Label
        solverTab                       matlab.ui.container.Tab
        solvertoleranceEditField        matlab.ui.control.NumericEditField
        solvertoleranceEditFieldLabel   matlab.ui.control.Label
        Forcesolver1itDropDown          matlab.ui.control.DropDown
        Forcesolver1itDropDownLabel     matlab.ui.control.Label
        solverDropDown                  matlab.ui.control.DropDown
        solverDropDownLabel             matlab.ui.control.Label
        bifurcationTab                  matlab.ui.container.Tab
        stop_on_bifurcationDropDown     matlab.ui.control.DropDown
        stop_on_bifurcationDropDownLabel  matlab.ui.control.Label
        bif_rand_dirDropDown            matlab.ui.control.DropDown
        bif_rand_dirDropDownLabel       matlab.ui.control.Label
        n_bif_searchEditField           matlab.ui.control.NumericEditField
        n_bif_searchEditFieldLabel      matlab.ui.control.Label
        bifurcationDropDown             matlab.ui.control.DropDown
        bifurcationDropDownLabel        matlab.ui.control.Label
        basicoptionsLabel               matlab.ui.control.Label
        ResetSettingsButton             matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Function_pathEditField          matlab.ui.control.EditField
        Function_pathEditFieldLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        PathContinuationtoolofLabel     matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        fig_num = NaN; % Figure number to plot in
        pos_Offset = [820,240]; % offset of figure
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,~,~)
           % Prevent overlapping reentry due to prompt replacement
          persistent inProgress
          if isempty(inProgress)
            inProgress = 1;  %#ok unused
          else
            return;
          end
         
          try
            % *** Prompt modification code goes here ***
            cwText = char(jTextArea.getText);
            app.TextArea_2.Value = cwText;
            % force prompt-change callback to fizzle-out...
            pause(0.02);
          catch
            % Never mind - ignore errors...
          end
         
          % Enable new callbacks now that the prompt has been modified
          inProgress = [];
         
        end  % setPromptFcn
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                clc;
                vars = {'corrector_method','stepsizecontrolmethod','step_size_alpha_max',...
                'step_size_angle','step_e_max','maxstepsize','minstepsize',...
                'bifurcation_method','n_bif_search','bif_rand_dir','plot_method',...
                'plot_pause','live_plot_fig','plot_var_of_interest','plot_vars_index',...
                'ShowDisplay','predictor_method','predictor_polynomial_adaptive','predictor_solver',...
                'predictor_polynomial_fit','predictor_polynomial_order','homotopy_method','homotopyerrorcounter',...
                'deflation','deflationerrorcounter','l_start','l_start',...
                'maxerrorcounter','n_iter_opt','n_step_max','stepback_error_counter',...
                'diffquot','alphareverse','includereverse','reverse_method',...
                'scaling_method','dscale0','dscale_min','solver_method',...
                'Forcesolver1it','solvertolerance','breakfunction','direction',...
                'closed_counter','closedcurvedetection','ds0','stop_on_bifurcation',...
                'v0'};
                clear(vars{:});

                % find folder
                if(~isdeployed)
                    cd(fileparts(which('Beispiel_01.m')));
                end
                addpath('..\src');
                
                % start showing command window
                try
                    jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                    jCmdWin = jDesktop.getClient('Command Window');
                    jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                    set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
                catch
                    warndlg('fatal error');
                end
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            app.Function_pathEditField.Value = 'test_cases';
            app.CorrectormethodDropDown.Value = 'sphere';
            app.stepsizecontrolmethodDropDown.Value = 'iterations';
            app.step_size_alpha_maxEditField.Value = 0.45;
            app.step_size_angleEditField.Value = (5/360)*(2*pi);
            app.step_e_maxEditField.Value = 0.3;
            app.maxstepsizeEditField_2.Value = 'inf';
            app.minstepsizeEditField.Value = '0';
            app.bifurcationDropDown.Value = 'on';
            app.n_bif_searchEditField.Value = 10;
            app.bif_rand_dirDropDown.Value = 'true';
            app.plotDropDown.Value = 'on';
            app.plot_pauseEditField.Value = 'false';
            app.live_plot_figEditField.Value = 'NaN';
            app.plot_var_of_interestEditField.Value = 'NaN';
            app.plot_vars_indexEditField_2.Value = '1:length(v0)';
            app.ShowDisplayDropDown.Value = 'true';
            app.predictorDropDown.Value = 'polynomial';
            app.predictor_polynomial_adaptiveDropDown.Value = 'false';
            app.predictor_solverDropDown.Value = 'false';
            app.predictor_polynomial_fitEditField.Value = 0;
            app.predictor_polynomial_orderEditField.Value = 1;
            app.homotopyDropDown.Value = 'fix';
            app.homotopyerrorcounterEditField.Value = 10;
            app.deflationDropDown.Value = 'true';
            app.deflationerrorcounterEditField.Value = 5;
            app.l_0EditField.Value = 'lams';
            app.l_startEditField.Value = 'lams';
            app.l_endEditField.Value = 'lame';
            app.l_targetEditField.Value = 'lame';
            app.maxerrorcounterEditField.Value = 20;
            app.n_iter_optEditField.Value = 3;
            app.n_step_maxEditField.Value = Inf;
            app.stepback_error_counterEditField.Value = 3;
            app.diffquotDropDown.Value = 'forward';
            app.alphareverseEditField.Value = pi/2;
            app.includereverseDropDown.Value = 'false';
            app.reverseDropDown.Value = 'angle';
            app.scalingDropDown.Value = 'dynamicdscale';
            app.dscale0EditField.Value = 'ones(length(v0)+1,1)';
            app.dscale_minEditField.Value = '1e-5';
            app.solverDropDown.Value = 'fsolve';
            app.Forcesolver1itDropDown.Value = 'false';
            app.solvertoleranceEditField.Value = 1e-06;
            app.breakfunctionEditField.Value = '@(f,J,v,l,break_fun_out) initial_break_function(f,J,v,l,break_fun_out)';
            app.directionEditField.Value = 'sign(l_end-l_start)*[zeros(size(v0));1]';
            app.closed_counterEditField.Value = 3;
            app.closedcurvedetectionDropDown.Value = 'false';
            app.startstepsizeEditField.Value = 'ds0';
            app.stop_on_bifurcationDropDown.Value = 'false';
            app.v_0EditField.Value = 'v0';
            app.Function_nameEditField.Value = 'testfun02';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if(~isdeployed)
                    cd(fileparts(which('Beispiel_01.m')));
            end
            % clear variables
            vars = {'corrector_method','stepsizecontrolmethod','step_size_alpha_max',...
                'step_size_angle','step_e_max','maxstepsize','minstepsize',...
                'bifurcation_method','n_bif_search','bif_rand_dir','plot_method',...
                'plot_pause','live_plot_fig','plot_var_of_interest','plot_vars_index',...
                'ShowDisplay','predictor_method','predictor_polynomial_adaptive','predictor_solver',...
                'predictor_polynomial_fit','predictor_polynomial_order','homotopy_method','homotopyerrorcounter',...
                'deflation','deflationerrorcounter','l_start','l_start',...
                'maxerrorcounter','n_iter_opt','n_step_max','stepback_error_counter',...
                'diffquot','alphareverse','includereverse','reverse_method',...
                'scaling_method','dscale0','dscale_min','solver_method',...
                'Forcesolver1it','solvertolerance','breakfunction','direction',...
                'closed_counter','closedcurvedetection','ds0','stop_on_bifurcation',...
                'v0'};
            clear(vars{:});
            
            % add function
            if strcmp(app.ImplementfunctionbyDropDown.Value, 'Path')
                fun_path = app.Function_pathEditField.Value;
                addpath(fun_path);
                eval(app.Function_nameEditField.Value);
            else
                function_code_lines = app.TextArea.Value;
                for k_lines = 1:length(function_code_lines)
                    eval(function_code_lines{k_lines});
                end
                
            end
            
            
            % get input values
            corrector_method = app.CorrectormethodDropDown.Value;
            stepsizecontrolmethod = app.stepsizecontrolmethodDropDown.Value;
            step_size_alpha_max = app.step_size_alpha_maxEditField.Value;
            step_size_angle = app.step_size_angleEditField.Value;
            step_e_max = app.step_e_maxEditField.Value;
            maxstepsize = app.maxstepsizeEditField_2.Value;
            minstepsize = app.minstepsizeEditField.Value;
            bifurcation_method = app.bifurcationDropDown.Value;
            n_bif_search = app.n_bif_searchEditField.Value;
            bif_rand_dir = app.bif_rand_dirDropDown.Value;
            plot_method = app.plotDropDown.Value;
            plot_pause = app.plot_pauseEditField.Value;
            live_plot_fig = app.live_plot_figEditField.Value;
            plot_var_of_interest = app.plot_var_of_interestEditField.Value;
            plot_vars_index = app.plot_vars_indexEditField_2.Value;
            ShowDisplay = app.ShowDisplayDropDown.Value;
            predictor_method = app.predictorDropDown.Value;
            predictor_polynomial_adaptive = app.predictor_polynomial_adaptiveDropDown.Value;
            predictor_solver = app.predictor_solverDropDown.Value;
            predictor_polynomial_fit = app.predictor_polynomial_fitEditField.Value;
            predictor_polynomial_order = app.predictor_polynomial_orderEditField.Value;
            homotopy_method = app.homotopyDropDown.Value;
            homotopyerrorcounter = app.homotopyerrorcounterEditField.Value;
            deflation = app.deflationDropDown.Value;
            deflationerrorcounter = app.deflationerrorcounterEditField.Value;
            l_0 = eval(app.l_0EditField.Value);
            l_start = eval(app.l_startEditField.Value);
            l_target = eval(app.l_targetEditField.Value);
            l_end = eval(app.l_endEditField.Value);
            maxerrorcounter = app.maxerrorcounterEditField.Value;
            n_iter_opt = app.n_iter_optEditField.Value;
            n_step_max = app.n_step_maxEditField.Value;
            stepback_error_counter = app.stepback_error_counterEditField.Value;
            diffquot = app.diffquotDropDown.Value;
            alphareverse = app.alphareverseEditField.Value;
            includereverse = app.includereverseDropDown.Value;
            reverse_method = app.reverseDropDown.Value;
            scaling_method = app.scalingDropDown.Value;
            dscale0 = app.dscale0EditField.Value;
            dscale_min = app.dscale_minEditField.Value;
            solver_method = app.solverDropDown.Value;
            Forcesolver1it = app.Forcesolver1itDropDown.Value;
            solvertolerance = app.solvertoleranceEditField.Value;
            breakfunction = app.breakfunctionEditField.Value;
            direction = app.directionEditField.Value;
            closed_counter = app.closed_counterEditField.Value;
            closedcurvedetection = app.closedcurvedetectionDropDown.Value;
            ds0 = eval(app.startstepsizeEditField.Value);
            stop_on_bifurcation = app.stop_on_bifurcationDropDown.Value;
            v0 = eval(app.v_0EditField.Value);
            
            % create figure
            live_plot_fig = eval(live_plot_fig);
            if isnan(live_plot_fig)
                close all;
                fig = figure(1);
                fig.Tag = 'app_fig';
                live_plot_fig = fig.Number;
                app.fig_num = live_plot_fig;
                fig.Position = [app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position(1:2) + app.pos_Offset, 968, 550];
                set(fig, 'MenuBar', 'none');
                set(fig, 'ToolBar', 'none');
                app.EnabletoolbarinplotCheckBox.Value = false;
                app.EnablemenubarinplotCheckBox.Value = false;
            else
                fig = figure(live_plot_fig);
                fig.Tag = 'app_fig';
                app.fig_num = live_plot_fig;
                fig.Position = [app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position(1:2) + app.pos_Offset, 968, 550];
                set(fig, 'MenuBar', 'none');
                set(fig, 'ToolBar', 'none');
                app.EnabletoolbarinplotCheckBox.Value = false;
                app.EnablemenubarinplotCheckBox.Value = false;
            end
            
            % start continuation
            continuation(fun,v0,l_start,l_end,ds0,...
                 'alpha_reverse',alphareverse,...
                 'bifurcation',bifurcation_method,...
                 'bif_rand_dir', eval(bif_rand_dir),...
                 'break_function',eval(breakfunction),...
                 'closed_counter', closed_counter,...
                 'closed_curve_detection',eval(closedcurvedetection),...
                 'corrector',corrector_method,...
                 'deflation',eval(deflation),...
                 'deflation_error_counter',deflationerrorcounter,...
                 'diffquot',diffquot,...
                 'direction',eval(direction),...
                 'display',eval(ShowDisplay),...
                 'ds_max',eval(maxstepsize),...
                 'ds_min',eval(minstepsize),...
                 'dscale0',eval(dscale0),...
                 'dscale_min', eval(dscale_min),...
                 'homotopy',homotopy_method,...
                 'homotopy_error_counter',homotopyerrorcounter,...
                 'include_reverse',eval(includereverse),...
                 'l_0',l_0,...
                 'l_target',l_target,...
                 'live_plot_fig', live_plot_fig,...
                 'max_error_counter',maxerrorcounter,...
                 'n_bif_search',n_bif_search,...
                 'n_iter_opt',n_iter_opt,...
                 'n_step_max',n_step_max,...
                 'plot',plot_method,...
                 'plot_pause',eval(plot_pause),...
                 'plot_var_of_interest', eval(plot_var_of_interest),...
                 'plot_vars_index',eval(plot_vars_index),...                
                 'predictor',predictor_method,...
                 'predictor_polynomial_adaptive',eval(predictor_polynomial_adaptive),...
                 'predictor_polynomial_fit',predictor_polynomial_fit,...
                 'predictor_polynomial_order',predictor_polynomial_order,...
                 'predictor_solver',eval(predictor_solver),...
                 'reverse',reverse_method,...
                 'scaling',scaling_method,...
                 'solver',solver_method,...
                 'solver_force1it',eval(Forcesolver1it),...
                 'solver_tol',solvertolerance,...
                 'stepback_error_counter',stepback_error_counter,...
                 'step_size_alpha_max',step_size_alpha_max,...
                 'step_size_angle',step_size_angle,...
                 'step_size_control',stepsizecontrolmethod,...
                 'step_size_e_max',step_e_max,...
                 'stop_on_bifurcation',eval(stop_on_bifurcation));
        end

        % Callback function
        function CloseallFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: ShowadvancedoptionsCheckBox
        function ShowadvancedoptionsCheckBoxValueChanged(app, event)
            value = app.ShowadvancedoptionsCheckBox.Value;
            if value
                app.TabGroup2.Visible = true;
                app.advancedoptionsLabel.Visible = true;
            else
                app.TabGroup2.Visible = false;
                app.advancedoptionsLabel.Visible = false;
            end
        end

        % Value changed function: ImplementfunctionbyDropDown
        function ImplementfunctionbyDropDownValueChanged(app, event)
            value = app.ImplementfunctionbyDropDown.Value;
            if strcmp(value,'Path')
                app.Function_pathEditField.Visible = true;
                app.Function_pathEditFieldLabel.Visible = true;
                app.Function_nameEditField.Visible = true;
                app.Function_nameEditFieldLabel.Visible = true;
                app.TextArea.Visible = false;
            else
                app.Function_pathEditField.Visible = false;
                app.Function_pathEditFieldLabel.Visible = false;
                app.Function_nameEditField.Visible = false;
                app.Function_nameEditFieldLabel.Visible = false;
                app.TextArea.Visible = true;
            end
        end

        % Close request function: 
        % continuationtoolInstituteofDynamicsandVibrationResearchUIFigure
        function appCloseRequest(app, event)
            close all;
            delete(app)
        end

        % Window button motion function: 
        % continuationtoolInstituteofDynamicsandVibrationResearchUIFigure
        function Motiondetected(app, event)
%             if ~isnan(app.fig_num)
%                 if ishandle(app.fig_num)
%                     fig = figure(app.fig_num);
%                     position = app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Position;
%                     siz = get(fig, 'Position');
%                     set(fig, 'Position', [position(1:2) + app.pos_Offset, siz(3:4)]);
%                     drawnow;
%                 end
%             end
        end

        % Value changed function: EnablemenubarinplotCheckBox
        function EnablemenubarinplotCheckBoxValueChanged(app, event)
            value = app.EnablemenubarinplotCheckBox.Value;
            value2 = app.EnabletoolbarinplotCheckBox.Value;
            if ishandle(app.fig_num)
                fig = figure(app.fig_num);
                if value
                    set(fig, 'MenuBar', 'figure');
                else
                    set(fig, 'MenuBar', 'none');
                end
            end
            if value && value2
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 632];
            elseif xor(value, value2)
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 600];
            else
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 580];
            end
        end

        % Value changed function: EnabletoolbarinplotCheckBox
        function EnabletoolbarinplotCheckBoxValueChanged(app, event)
            value = app.EnabletoolbarinplotCheckBox.Value;
            value2 = app.EnablemenubarinplotCheckBox.Value;
            if ishandle(app.fig_num)
                fig = figure(app.fig_num);
                if value
                    set(fig, 'ToolBar', 'figure');
                else
                    set(fig, 'ToolBar', 'none');
                end
            end
            if value && value2
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 632];
            elseif xor(value, value2) 
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 600];
            else
                pos_old = app.plotwillbeshownhereLabel.Position;
                app.plotwillbeshownhereLabel.Position = [pos_old(1:3), 580];
            end
        end

        % Value changed function: stepsizecontrolmethodDropDown
        function stepsizecontrolmethodDropDownValueChanged(app, event)
            value = app.stepsizecontrolmethodDropDown.Value;
            if strcmp(value,'angle')
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = true;
                app.step_size_angleEditField.Visible = true;
            elseif strcmp(value,'curvature')
                app.step_size_alpha_maxEditFieldLabel.Visible = true;
                app.step_size_alpha_maxEditField.Visible = true;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            elseif strcmp(value,'pid')
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = true;
                app.step_e_maxEditField.Visible = true;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            else
                app.step_size_alpha_maxEditFieldLabel.Visible = false;
                app.step_size_alpha_maxEditField.Visible = false;
                app.step_e_maxEditFieldLabel.Visible = false;
                app.step_e_maxEditField.Visible = false;
                app.step_size_angleEditFieldLabel.Visible = false;
                app.step_size_angleEditField.Visible = false;
            end
        end

        % Value changed function: plotDropDown
        function plotDropDownValueChanged(app, event)
            value = app.plotDropDown.Value;
            if strcmp(value,'off')
                app.plot_pauseEditField.Visible = false;
                app.plot_pauseEditFieldLabel.Visible = false;
                app.plot_var_of_interestEditField.Visible = false;
                app.plot_var_of_interestEditFieldLabel.Visible = false;
                app.plot_vars_indexEditField_2.Visible = false;
                app.plot_vars_indexEditField_2Label.Visible = false;
                app.live_plot_figEditField.Visible = false;
                app.live_plot_figEditFieldLabel.Visible = false;
            else
                app.plot_pauseEditField.Visible = true;
                app.plot_pauseEditFieldLabel.Visible = true;
                app.plot_var_of_interestEditField.Visible = true;
                app.plot_var_of_interestEditFieldLabel.Visible = true;
                app.plot_vars_indexEditField_2.Visible = true;
                app.plot_vars_indexEditField_2Label.Visible = true;
                app.live_plot_figEditField.Visible = true;
                app.live_plot_figEditFieldLabel.Visible = true;
            end
        end

        % Value changed function: bifurcationDropDown
        function bifurcationDropDownValueChanged(app, event)
            value = app.bifurcationDropDown.Value;
            if strcmp(value, 'on') || strcmp(value, 'mark') || strcmp(value, 'determine')
                app.stop_on_bifurcationDropDownLabel.Visible = true;
                app.stop_on_bifurcationDropDown.Visible = true;
                app.n_bif_searchEditFieldLabel.Visible = false;
                app.n_bif_searchEditField.Visible = false;
                app.bif_rand_dirDropDownLabel.Visible = false;
                app.bif_rand_dirDropDown.Visible = false;
            elseif strcmp(value, 'trace')
                app.stop_on_bifurcationDropDownLabel.Visible = true;
                app.stop_on_bifurcationDropDown.Visible = true;
                app.n_bif_searchEditFieldLabel.Visible = true;
                app.n_bif_searchEditField.Visible = true;
                app.bif_rand_dirDropDownLabel.Visible = true;
                app.bif_rand_dirDropDown.Visible = true;
            else
                app.stop_on_bifurcationDropDownLabel.Visible = false;
                app.stop_on_bifurcationDropDown.Visible = false;
                app.n_bif_searchEditFieldLabel.Visible = false;
                app.n_bif_searchEditField.Visible = false;
                app.bif_rand_dirDropDownLabel.Visible = false;
                app.bif_rand_dirDropDown.Visible = false;
            end
        end

        % Value changed function: scalingDropDown
        function scalingDropDownValueChanged(app, event)
            value = app.scalingDropDown.Value;
            if strcmp(value, 'off')
                app.dscale0EditFieldLabel.Visible = false;
                app.dscale0EditField.Visible = false;
                app.dscale_minEditFieldLabel.Visible = false;
                app.dscale_minEditField.Visible = false;
            else
                app.dscale0EditFieldLabel.Visible = true;
                app.dscale0EditField.Visible = true;
                app.dscale_minEditFieldLabel.Visible = true;
                app.dscale_minEditField.Visible = true;
            end
        end

        % Value changed function: closedcurvedetectionDropDown
        function closedcurvedetectionDropDownValueChanged(app, event)
            value = app.closedcurvedetectionDropDown.Value;
            if strcmp(value, 'true')
                app.closed_counterEditField.Visible = true;
                app.closed_counterEditFieldLabel.Visible = true;
            else
                app.closed_counterEditField.Visible = false;
                app.closed_counterEditFieldLabel.Visible = false;
            end
        end

        % Value changed function: homotopyDropDown
        function homotopyDropDownValueChanged(app, event)
            value = app.homotopyDropDown.Value;
            if strcmp(value, 'off')
                app.homotopyerrorcounterEditFieldLabel.Visible = false;
                app.homotopyerrorcounterEditField.Visible = false;
            else
                app.homotopyerrorcounterEditFieldLabel.Visible = true;
                app.homotopyerrorcounterEditField.Visible = true;
            end
        end

        % Value changed function: deflationDropDown
        function deflationDropDownValueChanged(app, event)
            value = app.deflationDropDown.Value;
            if strcmp(value, 'false')
                app.deflationerrorcounterEditFieldLabel.Visible = false;
                app.deflationerrorcounterEditField.Visible = false;
            else
                app.deflationerrorcounterEditFieldLabel.Visible = true;
                app.deflationerrorcounterEditField.Visible = true;
            end
        end

        % Menu selected function: OpencontinuationfunctionMenu
        function OpencontinuationfunctionMenuSelected(app, event)
            open continuation.m;
        end

        % Menu selected function: OpensrcfolderMenu
        function OpensrcfolderMenuSelected(app, event)
            winopen('..\src\');
        end

        % Menu selected function: WatchdocumentationMenu_2
        function WatchdocumentationMenu_2Selected(app, event)
            addpath('..\doc\html\');
            web('continuation.html');
        end

        % Menu selected function: WatchGitHubprojectMenu_2
        function WatchGitHubprojectMenu_2Selected(app, event)
            web('https://github.com/foersterIDS/Continuation','-new','-notoolbar');
        end

        % Menu selected function: ContactUsMenu
        function ContactUsMenuSelected(app, event)
            email = 'foerster@ids.uni-hannover.de';
            url = ['mailto:',email];
            web(url);
            disp(['Contact us via ',email]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure = uifigure('Visible', 'off');
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Name = 'continuation tool - Institute of Dynamics and Vibration Research';
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Icon = 'icon-1.png';
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.CloseRequestFcn = createCallbackFcn(app, @appCloseRequest, true);
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @Motiondetected, true);
            app.OpencontinuationfunctionMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.OpencontinuationfunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpencontinuationfunctionMenuSelected, true);
            app.OpencontinuationfunctionMenu.Text = 'Open continuation function';
            app.OpensrcfolderMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.OpensrcfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpensrcfolderMenuSelected, true);
            app.OpensrcfolderMenu.Text = 'Open src folder';
            app.WatchdocumentationMenu_2 = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.WatchdocumentationMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WatchdocumentationMenu_2Selected, true);
            app.WatchdocumentationMenu_2.Text = 'Watch documentation';
            app.WatchGitHubprojectMenu_2 = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.WatchGitHubprojectMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WatchGitHubprojectMenu_2Selected, true);
            app.WatchGitHubprojectMenu_2.Text = 'Watch GitHub project';
            app.ContactUsMenu = uimenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ContactUsMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactUsMenuSelected, true);
            app.ContactUsMenu.Text = 'Contact Us';
            app.TabGroup3 = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Tab = uitab(app.TabGroup3);
            app.Tab.Title = 'Tab';
            app.PathContinuationtoolofLabel = uilabel(app.Tab);
            app.PathContinuationtoolofLabel.Text = {'Use the below fields to start a path continuation.'; ''; 'You may either use the path of a .m-file with your function or implement the function as code.'; ''; 'Hover over a field to get information.'; ''};
            app.Tab2 = uitab(app.TabGroup3);
            app.Tab2.Title = 'Tab2';
            app.Label = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Label.Text = '';
            app.Function_pathEditFieldLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Function_pathEditFieldLabel.Tooltip = {'Enter the path of your function'};
            app.Function_pathEditFieldLabel.Text = 'Function_path';
            app.Function_pathEditField = uieditfield(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'text');
            app.Function_pathEditField.Placeholder = 'Enter path of your function';
            app.Function_pathEditField.Value = 'test_cases';
            app.RunButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetSettingsButton = uibutton(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.basicoptionsLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.basicoptionsLabel.Text = 'basic options';
            app.TabGroup = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.lambdaandloopspecificationsTab = uitab(app.TabGroup);
            app.lambdaandloopspecificationsTab.Title = 'lambda and loop specifications';
            app.l_0EditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_0EditFieldLabel.Tooltip = {'set start value different from l_start'};
            app.l_0EditFieldLabel.Text = 'l_0';
            app.l_0EditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_0EditField.Tooltip = {'set start value different from l_start'};
            app.l_0EditField.Value = 'lams';
            app.l_targetEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_targetEditFieldLabel.Tooltip = {'set end value different from l_end'};
            app.l_targetEditFieldLabel.Text = 'l_target';
            app.l_targetEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_targetEditField.Tooltip = {'set end value different from l_end'};
            app.l_targetEditField.Value = 'lame';
            app.maxerrorcounterEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.maxerrorcounterEditFieldLabel.Tooltip = {'set maximum number off errors in a row'};
            app.maxerrorcounterEditFieldLabel.Text = 'max error counter';
            app.maxerrorcounterEditField = uieditfield(app.lambdaandloopspecificationsTab, 'numeric');
            app.maxerrorcounterEditField.Tooltip = {'set maximum number off errors in a row'};
            app.maxerrorcounterEditField.Value = 20;
            app.n_iter_optEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.n_iter_optEditFieldLabel.Tooltip = {'set optimal number of iterations per step'};
            app.n_iter_optEditFieldLabel.Text = 'n_iter_opt';
            app.n_iter_optEditField = uieditfield(app.lambdaandloopspecificationsTab, 'numeric');
            app.n_iter_optEditField.Tooltip = {'set optimal number of iterations per step'};
            app.n_iter_optEditField.Value = 3;
            app.n_step_maxEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.n_step_maxEditFieldLabel.Tooltip = {'max number of loop runs'};
            app.n_step_maxEditFieldLabel.Text = 'n_step_max';
            app.n_step_maxEditField = uieditfield(app.lambdaandloopspecificationsTab, 'numeric');
            app.n_step_maxEditField.Tooltip = {'max number of loop runs'};
            app.n_step_maxEditField.Value = Inf;
            app.stepback_error_counterEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.stepback_error_counterEditFieldLabel.Tooltip = {'max number of errors before step back'};
            app.stepback_error_counterEditFieldLabel.Text = 'stepback_error_counter';
            app.stepback_error_counterEditField = uieditfield(app.lambdaandloopspecificationsTab, 'numeric');
            app.stepback_error_counterEditField.Tooltip = {'max number of errors before step back'};
            app.stepback_error_counterEditField.Value = 3;
            app.v_0EditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.v_0EditFieldLabel.Tooltip = {'Starting values for variables'};
            app.v_0EditFieldLabel.Text = 'v_0';
            app.v_0EditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.v_0EditField.Tooltip = {'Starting values for variables'};
            app.v_0EditField.Value = 'v0';
            app.l_startEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_startEditFieldLabel.Tooltip = {'starting lambda of function'};
            app.l_startEditFieldLabel.Text = 'l_start';
            app.l_startEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_startEditField.Tooltip = {'starting lambda of function'};
            app.l_startEditField.Value = 'lams';
            app.l_endEditFieldLabel = uilabel(app.lambdaandloopspecificationsTab);
            app.l_endEditFieldLabel.Tooltip = {'end lambda of function'};
            app.l_endEditFieldLabel.Text = 'l_end';
            app.l_endEditField = uieditfield(app.lambdaandloopspecificationsTab, 'text');
            app.l_endEditField.Tooltip = {'end lambda of function'};
            app.l_endEditField.Value = 'lame';
            app.CorrectorandstepsizemethodTab = uitab(app.TabGroup);
            app.CorrectorandstepsizemethodTab.Title = 'Corrector and stepsize method';
            app.CorrectormethodDropDownLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.CorrectormethodDropDownLabel.Tooltip = {'Choose method for corrector'};
            app.CorrectormethodDropDownLabel.Text = 'Corrector method';
            app.CorrectormethodDropDown = uidropdown(app.CorrectorandstepsizemethodTab);
            app.CorrectormethodDropDown.Items = {'sphere', 'linear', 'ellipsoid', 'ellipsoid2', 'unique'};
            app.CorrectormethodDropDown.Tooltip = {'Choose method for corrector'};
            app.CorrectormethodDropDown.Value = 'sphere';
            app.stepsizecontrolmethodDropDownLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.stepsizecontrolmethodDropDownLabel.Text = 'step size control method';
            app.stepsizecontrolmethodDropDown = uidropdown(app.CorrectorandstepsizemethodTab);
            app.stepsizecontrolmethodDropDown.Items = {'iterations', 'angle', 'curvature', 'pid'};
            app.stepsizecontrolmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @stepsizecontrolmethodDropDownValueChanged, true);
            app.stepsizecontrolmethodDropDown.Tooltip = {'Choose method for step size control'};
            app.stepsizecontrolmethodDropDown.Value = 'iterations';
            app.step_size_alpha_maxEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_size_alpha_maxEditFieldLabel.Visible = 'off';
            app.step_size_alpha_maxEditFieldLabel.Tooltip = {'defines max. local curvature'};
            app.step_size_alpha_maxEditFieldLabel.Text = 'step_size_alpha_max';
            app.step_size_alpha_maxEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'numeric');
            app.step_size_alpha_maxEditField.Visible = 'off';
            app.step_size_alpha_maxEditField.Tooltip = {'defines max. local curvature for step size control methode curvature'};
            app.step_size_alpha_maxEditField.Value = 0.45;
            app.step_size_angleEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_size_angleEditFieldLabel.Visible = 'off';
            app.step_size_angleEditFieldLabel.Text = 'step_size_angle';
            app.step_size_angleEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'numeric');
            app.step_size_angleEditField.Visible = 'off';
            app.step_size_angleEditField.Tooltip = {'defines max. angle for step size control method angle'};
            app.step_size_angleEditField.Value = 0.0872664625997165;
            app.step_e_maxEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.step_e_maxEditFieldLabel.Visible = 'off';
            app.step_e_maxEditFieldLabel.Text = 'step_e_max';
            app.step_e_maxEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'numeric');
            app.step_e_maxEditField.Visible = 'off';
            app.step_e_maxEditField.Tooltip = {'defines weight for step size control method pid'; ''};
            app.step_e_maxEditField.Value = 0.3;
            app.maxstepsizeEditField_2Label = uilabel(app.CorrectorandstepsizemethodTab);
            app.maxstepsizeEditField_2Label.Tooltip = {'maximum stepsize. Input can either be numerical or corresponding variable'};
            app.maxstepsizeEditField_2Label.Text = 'max. stepsize';
            app.maxstepsizeEditField_2 = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.maxstepsizeEditField_2.Tooltip = {'maximum stepsize. Input can either be numerical or corresponding variable'};
            app.maxstepsizeEditField_2.Value = 'inf';
            app.minstepsizeEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.minstepsizeEditFieldLabel.Tooltip = {'minimum stepsize. Input can either be numerical or corresponding variable'};
            app.minstepsizeEditFieldLabel.Text = 'min. stepsize';
            app.minstepsizeEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.minstepsizeEditField.Tooltip = {'minimum stepsize. Input can either be numerical or corresponding variable'};
            app.minstepsizeEditField.Value = '0';
            app.startstepsizeEditFieldLabel = uilabel(app.CorrectorandstepsizemethodTab);
            app.startstepsizeEditFieldLabel.Tooltip = {'step size to start with'};
            app.startstepsizeEditFieldLabel.Text = 'start stepsize';
            app.startstepsizeEditField = uieditfield(app.CorrectorandstepsizemethodTab, 'text');
            app.startstepsizeEditField.Tooltip = {'step size to start with'};
            app.startstepsizeEditField.Value = 'ds0';
            app.predictorTab = uitab(app.TabGroup);
            app.predictorTab.Title = 'predictor';
            app.predictorDropDownLabel = uilabel(app.predictorTab);
            app.predictorDropDownLabel.Text = 'predictor';
            app.predictorDropDown = uidropdown(app.predictorTab);
            app.predictorDropDown.Items = {'polynomial', 'tangential'};
            app.predictorDropDown.Tooltip = {'Determine used predictor'};
            app.predictorDropDown.Value = 'polynomial';
            app.predictor_polynomial_adaptiveDropDownLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_adaptiveDropDownLabel.Text = 'predictor_polynomial_adaptive';
            app.predictor_polynomial_adaptiveDropDown = uidropdown(app.predictorTab);
            app.predictor_polynomial_adaptiveDropDown.Items = {'false', 'true'};
            app.predictor_polynomial_adaptiveDropDown.Tooltip = {'Calculate polynomial fit and polynomial order adaptivly'};
            app.predictor_polynomial_adaptiveDropDown.Value = 'false';
            app.predictor_solverDropDownLabel = uilabel(app.predictorTab);
            app.predictor_solverDropDownLabel.Text = 'predictor_solver';
            app.predictor_solverDropDown = uidropdown(app.predictorTab);
            app.predictor_solverDropDown.Items = {'false', 'true'};
            app.predictor_solverDropDown.Value = 'false';
            app.predictor_polynomial_fitEditFieldLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_fitEditFieldLabel.Text = 'predictor_polynomial_fit';
            app.predictor_polynomial_fitEditField = uieditfield(app.predictorTab, 'numeric');
            app.predictor_polynomial_orderEditFieldLabel = uilabel(app.predictorTab);
            app.predictor_polynomial_orderEditFieldLabel.Text = 'predictor_polynomial_order';
            app.predictor_polynomial_orderEditField = uieditfield(app.predictorTab, 'numeric');
            app.predictor_polynomial_orderEditField.Value = 1;
            app.plotdisplayTab = uitab(app.TabGroup);
            app.plotdisplayTab.Title = 'plot & display';
            app.plotDropDownLabel = uilabel(app.plotdisplayTab);
            app.plotDropDownLabel.Text = 'plot';
            app.plotDropDown = uidropdown(app.plotdisplayTab);
            app.plotDropDown.Items = {'on', 'off', 'basic', 'detail', 'semilogx', 'semilogy', 'loglog'};
            app.plotDropDown.ValueChangedFcn = createCallbackFcn(app, @plotDropDownValueChanged, true);
            app.plotDropDown.Tooltip = {'Choose plot method'};
            app.plotDropDown.Value = 'on';
            app.plot_pauseEditFieldLabel = uilabel(app.plotdisplayTab);
            app.plot_pauseEditFieldLabel.Text = 'plot_pause';
            app.plot_pauseEditField = uieditfield(app.plotdisplayTab, 'text');
            app.plot_pauseEditField.Tooltip = {'Pause live plot until enter is pressed'};
            app.plot_pauseEditField.Value = 'false';
            app.plot_var_of_interestEditFieldLabel = uilabel(app.plotdisplayTab);
            app.plot_var_of_interestEditFieldLabel.Text = 'plot_var_of_interest';
            app.plot_var_of_interestEditField = uieditfield(app.plotdisplayTab, 'text');
            app.plot_var_of_interestEditField.Tooltip = {'Choose interesting variable'};
            app.plot_var_of_interestEditField.Value = 'NaN';
            app.plot_vars_indexEditField_2Label = uilabel(app.plotdisplayTab);
            app.plot_vars_indexEditField_2Label.Text = 'plot_vars_index';
            app.plot_vars_indexEditField_2 = uieditfield(app.plotdisplayTab, 'text');
            app.plot_vars_indexEditField_2.Tooltip = {'Choose variables to plot'};
            app.plot_vars_indexEditField_2.Value = '1:length(v0)';
            app.live_plot_figEditFieldLabel = uilabel(app.plotdisplayTab);
            app.live_plot_figEditFieldLabel.Text = 'live_plot_fig';
            app.live_plot_figEditField = uieditfield(app.plotdisplayTab, 'text');
            app.live_plot_figEditField.Tooltip = {'Choose a figure to plot in'};
            app.live_plot_figEditField.Value = 'NaN';
            app.ShowDisplayDropDownLabel = uilabel(app.plotdisplayTab);
            app.ShowDisplayDropDownLabel.Text = 'Show Display?';
            app.ShowDisplayDropDown = uidropdown(app.plotdisplayTab);
            app.ShowDisplayDropDown.Items = {'true', 'false'};
            app.ShowDisplayDropDown.Tooltip = {'Show display progress in command window'};
            app.ShowDisplayDropDown.Value = 'true';
            app.solverTab = uitab(app.TabGroup);
            app.solverTab.Title = 'solver';
            app.solverDropDownLabel = uilabel(app.solverTab);
            app.solverDropDownLabel.Text = 'solver';
            app.solverDropDown = uidropdown(app.solverTab);
            app.solverDropDown.Items = {'fsolve', 'fmincon', 'lsqnonlin', 'newton'};
            app.solverDropDown.Tooltip = {'determine solver-type'};
            app.solverDropDown.Value = 'fsolve';
            app.Forcesolver1itDropDownLabel = uilabel(app.solverTab);
            app.Forcesolver1itDropDownLabel.Text = 'Force solver 1 it';
            app.Forcesolver1itDropDown = uidropdown(app.solverTab);
            app.Forcesolver1itDropDown.Items = {'false', 'true'};
            app.Forcesolver1itDropDown.Tooltip = {'forces solver to do at least 1 iteration'};
            app.Forcesolver1itDropDown.Value = 'false';
            app.solvertoleranceEditFieldLabel = uilabel(app.solverTab);
            app.solvertoleranceEditFieldLabel.Text = 'solver tolerance';
            app.solvertoleranceEditField = uieditfield(app.solverTab, 'numeric');
            app.solvertoleranceEditField.Tooltip = {'Define solver tolerance'};
            app.solvertoleranceEditField.Value = 1e-06;
            app.bifurcationTab = uitab(app.TabGroup);
            app.bifurcationTab.Tooltip = {'bifurcation analysis'};
            app.bifurcationTab.Title = 'bifurcation';
            app.bifurcationDropDownLabel = uilabel(app.bifurcationTab);
            app.bifurcationDropDownLabel.Text = 'bifurcation';
            app.bifurcationDropDown = uidropdown(app.bifurcationTab);
            app.bifurcationDropDown.Items = {'on', 'off', 'mark', 'trace', 'determine'};
            app.bifurcationDropDown.ValueChangedFcn = createCallbackFcn(app, @bifurcationDropDownValueChanged, true);
            app.bifurcationDropDown.Value = 'on';
            app.n_bif_searchEditFieldLabel = uilabel(app.bifurcationTab);
            app.n_bif_searchEditFieldLabel.Visible = 'off';
            app.n_bif_searchEditFieldLabel.Text = 'n_bif_search';
            app.n_bif_searchEditField = uieditfield(app.bifurcationTab, 'numeric');
            app.n_bif_searchEditField.Visible = 'off';
            app.n_bif_searchEditField.Tooltip = {'maximum trials to find a new branch nearby a branch bifurcation'};
            app.n_bif_searchEditField.Value = 10;
            app.bif_rand_dirDropDownLabel = uilabel(app.bifurcationTab);
            app.bif_rand_dirDropDownLabel.Visible = 'off';
            app.bif_rand_dirDropDownLabel.Text = 'bif_rand_dir';
            app.bif_rand_dirDropDown = uidropdown(app.bifurcationTab);
            app.bif_rand_dirDropDown.Items = {'true', 'false'};
            app.bif_rand_dirDropDown.Visible = 'off';
            app.bif_rand_dirDropDown.Tooltip = {'ALso use random directions to find new branch at bifurcation point'};
            app.bif_rand_dirDropDown.Value = 'true';
            app.stop_on_bifurcationDropDownLabel = uilabel(app.bifurcationTab);
            app.stop_on_bifurcationDropDownLabel.Text = 'stop_on_bifurcation';
            app.stop_on_bifurcationDropDown = uidropdown(app.bifurcationTab);
            app.stop_on_bifurcationDropDown.Items = {'false', 'true'};
            app.stop_on_bifurcationDropDown.Tooltip = {'Force function to stop when point of bifurcation is found'};
            app.stop_on_bifurcationDropDown.Value = 'false';
            app.TabGroup2 = uitabgroup(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TabGroup2.Visible = 'off';
            app.numericjacobianTab_2 = uitab(app.TabGroup2);
            app.numericjacobianTab_2.Title = 'numeric jacobian';
            app.diffquotDropDownLabel = uilabel(app.numericjacobianTab_2);
            app.diffquotDropDownLabel.Text = 'diffquot';
            app.diffquotDropDown = uidropdown(app.numericjacobianTab_2);
            app.diffquotDropDown.Items = {'forward', 'central'};
            app.diffquotDropDown.Tooltip = {'Difference quotient for calculation of numeric jacobian'};
            app.diffquotDropDown.Value = 'forward';
            app.reversecontrolTab = uitab(app.TabGroup2);
            app.reversecontrolTab.Title = 'reverse control';
            app.alphareverseEditFieldLabel = uilabel(app.reversecontrolTab);
            app.alphareverseEditFieldLabel.Text = 'alpha reverse';
            app.alphareverseEditField = uieditfield(app.reversecontrolTab, 'numeric');
            app.alphareverseEditField.Value = 1.5707963267949;
            app.includereverseDropDownLabel = uilabel(app.reversecontrolTab);
            app.includereverseDropDownLabel.Text = 'include reverse';
            app.includereverseDropDown = uidropdown(app.reversecontrolTab);
            app.includereverseDropDown.Items = {'false', 'true'};
            app.includereverseDropDown.Value = 'false';
            app.reverseDropDownLabel = uilabel(app.reversecontrolTab);
            app.reverseDropDownLabel.Text = 'reverse';
            app.reverseDropDown = uidropdown(app.reversecontrolTab);
            app.reverseDropDown.Items = {'angle', 'jacobian'};
            app.reverseDropDown.Value = 'angle';
            app.scalingTab = uitab(app.TabGroup2);
            app.scalingTab.Title = 'scaling';
            app.scalingDropDownLabel = uilabel(app.scalingTab);
            app.scalingDropDownLabel.Text = 'scaling';
            app.scalingDropDown = uidropdown(app.scalingTab);
            app.scalingDropDown.Items = {'dynamicdscale', 'staticdscale'};
            app.scalingDropDown.ValueChangedFcn = createCallbackFcn(app, @scalingDropDownValueChanged, true);
            app.scalingDropDown.Tooltip = {'type of scaling'};
            app.scalingDropDown.Value = 'dynamicdscale';
            app.dscale0EditFieldLabel = uilabel(app.scalingTab);
            app.dscale0EditFieldLabel.Text = 'dscale0';
            app.dscale0EditField = uieditfield(app.scalingTab, 'text');
            app.dscale0EditField.Tooltip = {'starting value for scaling'};
            app.dscale0EditField.Value = 'ones(length(v0)+1,1)';
            app.dscale_minEditFieldLabel = uilabel(app.scalingTab);
            app.dscale_minEditFieldLabel.Text = 'dscale_min';
            app.dscale_minEditField = uieditfield(app.scalingTab, 'text');
            app.dscale_minEditField.Tooltip = {'minimal value for scaling'};
            app.dscale_minEditField.Value = '1e-5';
            app.breakTab = uitab(app.TabGroup2);
            app.breakTab.Title = 'break';
            app.breakfunctionEditFieldLabel = uilabel(app.breakTab);
            app.breakfunctionEditFieldLabel.Text = 'break function';
            app.breakfunctionEditField = uieditfield(app.breakTab, 'text');
            app.breakfunctionEditField.Tooltip = {'Criterion for breaking the continuation'};
            app.breakfunctionEditField.Value = '@(f,J,v,l,break_fun_out) initial_break_function(f,J,v,l,break_fun_out)';
            app.initialdirectionTab_2 = uitab(app.TabGroup2);
            app.initialdirectionTab_2.Title = 'initial direction';
            app.directionEditFieldLabel = uilabel(app.initialdirectionTab_2);
            app.directionEditFieldLabel.Text = 'direction';
            app.directionEditField = uieditfield(app.initialdirectionTab_2, 'text');
            app.directionEditField.Tooltip = {'set initial direction'};
            app.directionEditField.Value = 'sign(l_end-l_start)*[zeros(size(v0));1]';
            app.closedcurvedetectionTab_2 = uitab(app.TabGroup2);
            app.closedcurvedetectionTab_2.Title = 'closed curve detection';
            app.closed_counterEditFieldLabel = uilabel(app.closedcurvedetectionTab_2);
            app.closed_counterEditFieldLabel.Visible = 'off';
            app.closed_counterEditFieldLabel.Text = 'closed_counter';
            app.closed_counterEditField = uieditfield(app.closedcurvedetectionTab_2, 'numeric');
            app.closed_counterEditField.Visible = 'off';
            app.closed_counterEditField.Tooltip = {'counter for closed curve detection'};
            app.closed_counterEditField.Value = 3;
            app.closedcurvedetectionDropDownLabel = uilabel(app.closedcurvedetectionTab_2);
            app.closedcurvedetectionDropDownLabel.Text = 'closed curve detection';
            app.closedcurvedetectionDropDown = uidropdown(app.closedcurvedetectionTab_2);
            app.closedcurvedetectionDropDown.Items = {'false', 'true'};
            app.closedcurvedetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @closedcurvedetectionDropDownValueChanged, true);
            app.closedcurvedetectionDropDown.Tooltip = {'Determine if continuation should end if a closed curve is found'};
            app.closedcurvedetectionDropDown.Value = 'false';
            app.homotopyTab = uitab(app.TabGroup2);
            app.homotopyTab.Title = 'homotopy';
            app.homotopyDropDownLabel = uilabel(app.homotopyTab);
            app.homotopyDropDownLabel.Text = 'homotopy';
            app.homotopyDropDown = uidropdown(app.homotopyTab);
            app.homotopyDropDown.Items = {'fix', 'newton', 'f2', 'on', 'off'};
            app.homotopyDropDown.ValueChangedFcn = createCallbackFcn(app, @homotopyDropDownValueChanged, true);
            app.homotopyDropDown.Tooltip = {'determine homotopy-type'};
            app.homotopyDropDown.Value = 'fix';
            app.homotopyerrorcounterEditFieldLabel = uilabel(app.homotopyTab);
            app.homotopyerrorcounterEditFieldLabel.Text = 'homotopy error counter';
            app.homotopyerrorcounterEditField = uieditfield(app.homotopyTab, 'numeric');
            app.homotopyerrorcounterEditField.Tooltip = {'set maximum number off errors in a row using homotopy'};
            app.homotopyerrorcounterEditField.Value = 10;
            app.deflationTab = uitab(app.TabGroup2);
            app.deflationTab.Title = 'deflation';
            app.deflationDropDownLabel = uilabel(app.deflationTab);
            app.deflationDropDownLabel.Text = 'deflation';
            app.deflationDropDown = uidropdown(app.deflationTab);
            app.deflationDropDown.Items = {'true', 'false'};
            app.deflationDropDown.ValueChangedFcn = createCallbackFcn(app, @deflationDropDownValueChanged, true);
            app.deflationDropDown.Tooltip = {'set deflation on/off'};
            app.deflationDropDown.Value = 'true';
            app.deflationerrorcounterEditFieldLabel = uilabel(app.deflationTab);
            app.deflationerrorcounterEditFieldLabel.Text = 'deflation error counter';
            app.deflationerrorcounterEditField = uieditfield(app.deflationTab, 'numeric');
            app.deflationerrorcounterEditField.Tooltip = {'error counter for deflation'};
            app.deflationerrorcounterEditField.Value = 5;
            app.advancedoptionsLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.advancedoptionsLabel.Visible = 'off';
            app.advancedoptionsLabel.Text = 'advanced options';
            app.Function_nameEditFieldLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Function_nameEditFieldLabel.Tooltip = {'Enter the name of your function'};
            app.Function_nameEditFieldLabel.Text = 'Function_name';
            app.Image = uiimage(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Image.ImageSource = 'ids_1000.png';
            app.Image2 = uiimage(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Image2.ImageSource = 'icon.svg';
            app.Function_nameEditField = uieditfield(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure, 'text');
            app.Function_nameEditField.Placeholder = 'Enter the name of your function';
            app.Function_nameEditField.Value = 'testfun02';
            app.ShowadvancedoptionsCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ShowadvancedoptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowadvancedoptionsCheckBoxValueChanged, true);
            app.ShowadvancedoptionsCheckBox.Text = 'Show advanced options?';
            app.Image3 = uiimage(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.Image3.ImageSource = 'luh_1000.png';
            app.ImplementfunctionbyDropDownLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ImplementfunctionbyDropDownLabel.Tooltip = {'You can choose to implement the function by its path or as code.'};
            app.ImplementfunctionbyDropDownLabel.Text = 'Implement function by...';
            app.ImplementfunctionbyDropDown = uidropdown(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.ImplementfunctionbyDropDown.Items = {'Path', 'Function Handle'};
            app.ImplementfunctionbyDropDown.ValueChangedFcn = createCallbackFcn(app, @ImplementfunctionbyDropDownValueChanged, true);
            app.ImplementfunctionbyDropDown.Tooltip = {'You can choose to implement the function by its path or as code.'};
            app.ImplementfunctionbyDropDown.Value = 'Path';
            app.pathcontinuationtoolLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.pathcontinuationtoolLabel.Text = 'path continuation tool';
            app.TextArea = uitextarea(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TextArea.Visible = 'off';
            app.TextArea.Tooltip = {'Enter your function as code. Note that all variables must be initialized here.'};
            app.TextArea.Placeholder = 'Enter your function and needed variables...';
            app.FunctionintegrationLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.FunctionintegrationLabel.Text = 'Function integration';
            app.TextArea_2 = uitextarea(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.TextArea_2.Editable = 'off';
            app.CommandWindowLabel = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.IDS2021TidoKubatschek18082021Label = uilabel(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.IDS2021TidoKubatschek18082021Label.Text = '© IDS 2021, Tido Kubatschek (18.08.2021)';
            app.EnablemenubarinplotCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.EnablemenubarinplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablemenubarinplotCheckBoxValueChanged, true);
            app.EnablemenubarinplotCheckBox.Text = 'Enable menu bar in plot?';
            app.EnabletoolbarinplotCheckBox = uicheckbox(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            app.EnabletoolbarinplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabletoolbarinplotCheckBoxValueChanged, true);
            app.EnabletoolbarinplotCheckBox.Text = 'Enable toolbar in plot?';
            app.ContextMenu = uicontextmenu(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure);
            % Assign app.ContextMenu
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continuation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.continuationtoolInstituteofDynamicsandVibrationResearchUIFigure)
        end
    end
end